classdef calcium_trace_navigator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GeorgesCalciumTraceNavigatorUIFigure  matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        PlotbyButtonGroup        matlab.ui.container.ButtonGroup
        TrialButton              matlab.ui.control.ToggleButton
        ROIButton                matlab.ui.control.ToggleButton
        SingleTraceButton        matlab.ui.control.ToggleButton
        TrialSpinnerLabel        matlab.ui.control.Label
        TrialSpinner             matlab.ui.control.Spinner
        ROISpinnerLabel          matlab.ui.control.Label
        ROISpinner               matlab.ui.control.Spinner
        HighlighttraceButton     matlab.ui.control.StateButton
        xticksEditFieldLabel     matlab.ui.control.Label
        xticksEditField          matlab.ui.control.EditField
        nTrials_Label            matlab.ui.control.Label
        nROIs_Label              matlab.ui.control.Label
        HelpButton               matlab.ui.control.StateButton
        HelpLabel                matlab.ui.control.Label
        ThresholdEditFieldLabel  matlab.ui.control.Label
        ThresholdEditField       matlab.ui.control.EditField
        AverageButton            matlab.ui.control.StateButton
        FilterButton             matlab.ui.control.StateButton
        Lamp                     matlab.ui.control.Lamp
        HeatmapButton            matlab.ui.control.StateButton
        WaterfallButton          matlab.ui.control.StateButton
        dffdataname              matlab.ui.control.EditField
        RightPanel               matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        dffarray; % the array that's to be plotted itself
    end
    
    methods (Access = public) % I honestly don't know how public/private is different
        
        function plotdata(app)
            % -- Define data and settings
            
            trial = app.TrialSpinner.Value;
            roi = app.ROISpinner.Value;
            plottype = app.PlotbyButtonGroup.SelectedObject.Text;
            getdffarray(app) % reload dffarray from workspace
            nTrials = size(app.dffarray,2);
            
            % -- Basic data preparation
            
            switch plottype % change the data depending request plot type
                case 'Trial'
                    data = app.dffarray{trial};
                case 'ROI'
                    data = cell(nTrials,1);
                    for xtrial = 1:nTrials
                        data{xtrial} = app.dffarray{xtrial}(roi,:); % insert each trial into cell
                    end
                    data = padcat(data{:}); % convert cell array into matrix, padding NaNs at end
                    % padcat is a function from the Matlab File Exchange
                case 'Single Trace'
                    data = app.dffarray{trial}(roi,:);
            end
            
            % -- Optional data processing
            
            % thresholding
            passthreshold = true(size(data,1),1);
            if app.FilterButton.Value % if filtering is on, then check for threshold passing
                if isempty(app.ThresholdEditField.Value) || any(isletter(app.ThresholdEditField.Value))
                    return % if value is wack then don't execute
                end
                for xrow = 1:size(data,1)
                    if ~any(data(xrow,:) >= str2double(app.ThresholdEditField.Value))
                        passthreshold(xrow) = false; % mark rows that don't have values above threshold
                    end
                end
                
            end
            
            % waterfall
            if app.WaterfallButton.Value
                for xrow = 1:size(data,1)
                    data(xrow,:) = -data(xrow,:) + xrow; % invert and add 1 for each row
                end
            end
            
            % removal of traces that failed threshold check
            if any(~passthreshold)
                data(~passthreshold,:) = NaN(sum(~passthreshold),size(data,2));
            end
            
            % -- Plot data
            plot(app.UIAxes,data'); % core line plot
            ylim(app.UIAxes,[-inf inf])
            
            % aesthetics
            app.UIAxes.Title.String = sprintf('Trial #%i ROI #%i',trial,roi); % title the plot
            if ~isempty(app.xticksEditField.Value) && ~any(isletter(app.xticksEditField.Value)) % account for possible wack values
                xticks(app.UIAxes,... % set ticks
                    0:str2double(app.xticksEditField.Value):size(app.dffarray{trial},2))
            end
            xlim(app.UIAxes,[0 size(data,2)]);
            grid(app.UIAxes,'on');
            
            % draw average trace
            if app.AverageButton.Value
                trace = nanmean(data,1); % mean of data along 1st dimension
                hold(app.UIAxes,'on')
                plot(app.UIAxes,trace,'r','LineWidth',1.4)
                hold(app.UIAxes,'off')
            end
            
            % draw threshold line
            if ~isempty(app.ThresholdEditField.Value) && ~app.WaterfallButton.Value && ~any(isletter(app.ThresholdEditField.Value))
                yline(app.UIAxes,str2double(app.ThresholdEditField.Value),':');
            end
            
            % draw highlighted trace
            if app.HighlighttraceButton.Value
                hold(app.UIAxes,'on')
                trace = app.dffarray{trial}(roi,:); % get trace of interest
                if app.WaterfallButton.Value % account for waterfall
                    trace = -trace;
                    switch plottype
                        case 'Trial'
                            trace = trace + roi;
                        case 'ROI'
                            trace = trace + trial;
                    end
                end
                plot(app.UIAxes,trace,'k','LineWidth',1.5); % plot in heavier black
                hold(app.UIAxes,'off')
            end
            
            colorbar(app.UIAxes,'off')
            if app.HeatmapButton.Value
                imagesc(app.UIAxes,data) % overwrite the plot with heatmap data
                app.UIAxes.XGrid = 'On'; % doesn't work for some reason
                app.UIAxes.YGrid = 'On';
                ylim(app.UIAxes,[0.5 size(data,1)+0.5])
                colorbar(app.UIAxes)
                if app.HighlighttraceButton.Value
                    hold(app.UIAxes,'on')
                    switch plottype
                        case 'Trial'
                            plot(app.UIAxes,[0 size(data,2)],[roi roi],'w')
                        case 'ROI'
                            plot(app.UIAxes,[0 size(data,2)],[trial trial],'w')
                    end
                    hold(app.UIAxes,'off')
                end
            end
            
            if app.WaterfallButton.Value || app.HeatmapButton.Value
                app.UIAxes.YDir = 'reverse'; % trials from top to bottom is the norm
            else
                app.UIAxes.YDir = 'normal';
                yline(app.UIAxes,0,'--');
            end
        end


        function getdffarray(app)
            dffname = app.dffdataname.Value;
            if evalin('base',...
                    sprintf('exist(''%s'')',dffname)) % check if dffarray exists
            data = evalin('base',dffname); % get dffarray from the workspace
            if iscell(data) && ~isempty(data) && any(size(data) == 1) % check if dffarray matches requirements
                app.dffarray = data;
                
                % Convert between dffmat to dffmatrix if required
                if all(size(data) > 1)
                    nTrials = size(data,1);
                    nRois = size(data,2);
                    
                    dffdata = cell(1,nTrials);
                    for trial = 1:nTrials
                        nFrames = numel(data{trial,1});
                        tempmatrix = NaN(nRois,nFrames);
                        for roi = 1:nRois
                            tempmatrix(roi,:) = data{trial,roi};
                        end
                        dffdata{trial} = tempmatrix;
                    end
                    data = dffdata; % overwrite dffmat with dffarrray
                end
                
                app.nTrials_Label.Text = sprintf('/%i',numel(data)); % update text beside spinners
                app.nROIs_Label.Text = sprintf('/%i',size(data{1},1));
                app.Lamp.Color = [0.00,1.00,0.00]; % set lamp to green
            else
                fprintf(2,'%s failed to meet criteria\n',dffname) % 2 specifies red colour
                app.dffarray = {NaN};
                app.Lamp.Color = [1.00,1.00,0.00]; % set lamp to yellow
                return
            end
            else
                fprintf(2,'%s does not exist in the Workspace\n',dffname)
                app.dffarray = {NaN};
                app.Lamp.Color = [1.00,0.00,0.00]; % set lamp to red
                return
            end
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            plotdata(app);
            app.HelpLabel.Visible = false;
        end

        % Value changed function: TrialSpinner
        function TrialSpinnerValueChanged(app, event)
            getdffarray(app)
            if app.TrialSpinner.Value < 1 % if else to prevent impossible indexing
                app.TrialSpinner.Value = 1;
            elseif app.TrialSpinner.Value > size(app.dffarray,2)
                app.TrialSpinner.Value = size(app.dffarray,2);
            else
                plotdata(app);
            end
        end

        % Selection changed function: PlotbyButtonGroup
        function PlotbyButtonGroupSelectionChanged(app, event)
            app.WaterfallButtonValueChanged % reload Waterfall button in case Single Trace was selected
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.GeorgesCalciumTraceNavigatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: ROISpinner
        function ROISpinnerValueChanged(app, event)
            getdffarray(app)
            if app.ROISpinner.Value < 1 % if else to prevent impossible indexing
                app.ROISpinner.Value = 1;
            elseif app.ROISpinner.Value > size(app.dffarray{1},1)
                app.ROISpinner.Value = size(app.dffarray{1},1);
            else
                plotdata(app);
            end
        end

        % Value changed function: HighlighttraceButton
        function HighlighttraceButtonValueChanged(app, event)
            plotdata(app);
        end

        % Value changed function: WaterfallButton
        function WaterfallButtonValueChanged(app, event)
            if app.WaterfallButton.Value
                app.AverageButton.Value = false;
                app.HeatmapButton.Value = false;
            end
            if strcmp(app.PlotbyButtonGroup.SelectedObject.Text, 'Single Trace') % prevent waterfall and single trace from being used together
                app.WaterfallButton.Value = false;
            end
            plotdata(app);
        end

        % Window key press function: 
        % GeorgesCalciumTraceNavigatorUIFigure
        function TotalWindowKeyPress(app, event)
            switch event.Key
                % key press will change a value and then callback to a
                % ValueChanged function to make the relevant action happen
                
                % -- General navigation
                case 'leftarrow'
                    app.TrialSpinner.Value = app.TrialSpinner.Value - 1;
                    app.TrialSpinnerValueChanged
                case 'rightarrow'
                    app.TrialSpinner.Value = app.TrialSpinner.Value + 1;
                    app.TrialSpinnerValueChanged
                case 'downarrow'
                    app.ROISpinner.Value = app.ROISpinner.Value - 1;
                    app.ROISpinnerValueChanged
                case 'uparrow'
                    app.ROISpinner.Value = app.ROISpinner.Value + 1;
                    app.ROISpinnerValueChanged
                    
                    % -- Other stuff
                case 'm'
                    switch app.HeatmapButton.Value
                        case 1; app.HeatmapButton.Value = 0;
                        case 0; app.HeatmapButton.Value = 1;
                    end
                    app.HeatmapButtonValueChanged
                case 'w'
                    switch app.WaterfallButton.Value
                        case 1; app.WaterfallButton.Value = 0;
                        case 0; app.WaterfallButton.Value = 1;
                    end
                    app.WaterfallButtonValueChanged
                case 'n' % copy of w (next to m) for high use
                    switch app.WaterfallButton.Value
                        case 1; app.WaterfallButton.Value = 0;
                        case 0; app.WaterfallButton.Value = 1;
                    end
                    app.WaterfallButtonValueChanged
                case 'h'
                    switch app.HighlighttraceButton.Value
                        case 1; app.HighlighttraceButton.Value = 0;
                        case 0; app.HighlighttraceButton.Value = 1;
                    end
                    app.HighlighttraceButtonValueChanged
                case 't'
                    app.TrialButton.Value = 1;
                    app.PlotbyButtonGroupSelectionChanged
                case 'r'
                    app.ROIButton.Value = 1;
                    app.PlotbyButtonGroupSelectionChanged
                case 's'
                    app.SingleTraceButton.Value = 1;
                    app.PlotbyButtonGroupSelectionChanged
                case 'a'
                    switch app.AverageButton.Value
                        case 1; app.AverageButton.Value = 0;
                        case 0; app.AverageButton.Value = 1;
                    end
                    app.AverageButtonValueChanged
                case 'f'
                    switch app.FilterButton.Value
                        case 1; app.FilterButton.Value = 0;
                        case 0; app.FilterButton.Value = 1;
                    end
                    app.FilterButtonValueChanged
                otherwise
            end
            
        end

        % Value changed function: HelpButton
        function HelpButtonValueChanged(app, event)
            switch app.HelpButton.Value
                case 1
                    app.HelpLabel.Visible = 1;
                case 0
                    app.HelpLabel.Visible = 0;
            end
            
        end

        % Value changed function: AverageButton
        function AverageButtonValueChanged(app, event)
            if app.AverageButton.Value
                app.WaterfallButton.Value = 0;
            end
            plotdata(app);
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            plotdata(app);
        end

        % Value changed function: FilterButton
        function FilterButtonValueChanged(app, event)
            plotdata(app);
        end

        % Value changed function: xticksEditField
        function xticksEditFieldValueChanged(app, event)
            plotdata(app);
        end

        % Value changed function: HeatmapButton
        function HeatmapButtonValueChanged(app, event)
            if app.HeatmapButton.Value
                app.WaterfallButton.Value = false;
            end
            plotdata(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GeorgesCalciumTraceNavigatorUIFigure = uifigure('Visible', 'off');
            app.GeorgesCalciumTraceNavigatorUIFigure.Name = 'George''s Calcium Trace Navigator';
            app.GeorgesCalciumTraceNavigatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GeorgesCalciumTraceNavigatorUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @TotalWindowKeyPress, true);
            app.GridLayout = uigridlayout(app.GeorgesCalciumTraceNavigatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Controls';
            app.PlotbyButtonGroup = uibuttongroup(app.LeftPanel);
            app.PlotbyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotbyButtonGroupSelectionChanged, true);
            app.PlotbyButtonGroup.Title = 'Plot by:';
            app.TrialButton = uitogglebutton(app.PlotbyButtonGroup);
            app.TrialButton.Tooltip = {'show traces for current trial'};
            app.TrialButton.Text = 'Trial';
            app.TrialButton.Value = true;
            app.ROIButton = uitogglebutton(app.PlotbyButtonGroup);
            app.ROIButton.Tooltip = {'show traces for current ROI'};
            app.ROIButton.Text = 'ROI';
            app.SingleTraceButton = uitogglebutton(app.PlotbyButtonGroup);
            app.SingleTraceButton.Tooltip = {'show only the focus trace'};
            app.SingleTraceButton.Text = 'Single Trace';
            app.TrialSpinnerLabel = uilabel(app.LeftPanel);
            app.TrialSpinnerLabel.Text = 'Trial';
            app.TrialSpinner = uispinner(app.LeftPanel);
            app.TrialSpinner.ValueChangedFcn = createCallbackFcn(app, @TrialSpinnerValueChanged, true);
            app.TrialSpinner.Value = 1;
            app.ROISpinnerLabel = uilabel(app.LeftPanel);
            app.ROISpinnerLabel.Text = 'ROI';
            app.ROISpinner = uispinner(app.LeftPanel);
            app.ROISpinner.ValueChangedFcn = createCallbackFcn(app, @ROISpinnerValueChanged, true);
            app.ROISpinner.Value = 1;
            app.HighlighttraceButton = uibutton(app.LeftPanel, 'state');
            app.HighlighttraceButton.ValueChangedFcn = createCallbackFcn(app, @HighlighttraceButtonValueChanged, true);
            app.HighlighttraceButton.Tooltip = {'show specific trace in bolded black'};
            app.HighlighttraceButton.Text = 'Highlight trace';
            app.HighlighttraceButton.Value = true;
            app.xticksEditFieldLabel = uilabel(app.LeftPanel);
            app.xticksEditFieldLabel.Text = 'xticks';
            app.xticksEditField = uieditfield(app.LeftPanel, 'text');
            app.xticksEditField.ValueChangedFcn = createCallbackFcn(app, @xticksEditFieldValueChanged, true);
            app.xticksEditField.Tooltip = {'define spacing of x-axis ticks'};
            app.xticksEditField.Value = '30';
            app.nTrials_Label = uilabel(app.LeftPanel);
            app.nTrials_Label.Text = '/?';
            app.nROIs_Label = uilabel(app.LeftPanel);
            app.nROIs_Label.Text = '/?';
            app.HelpButton = uibutton(app.LeftPanel, 'state');
            app.HelpButton.ValueChangedFcn = createCallbackFcn(app, @HelpButtonValueChanged, true);
            app.HelpButton.Text = 'Help';
            app.HelpLabel = uilabel(app.LeftPanel);
            app.HelpLabel.Text = {'left/right keys move trials; down/up keys move ROIs'; 'T: plot by trial; R: plot by ROI; S: plot by single trace'; 'H: highlight trace; W: waterfall; M: heatmap;'; 'A: Average; F: Filter'; ''; 'xticks changes interval between the xtick lines'; 'Threshold draws line'};
            app.ThresholdEditFieldLabel = uilabel(app.LeftPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.LeftPanel, 'text');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Tooltip = {'draw horizontal line and define value for Filter button'};
            app.ThresholdEditField.Value = '1';
            app.AverageButton = uibutton(app.LeftPanel, 'state');
            app.AverageButton.ValueChangedFcn = createCallbackFcn(app, @AverageButtonValueChanged, true);
            app.AverageButton.Tooltip = {'average visible traces'};
            app.AverageButton.Text = 'Average';
            app.FilterButton = uibutton(app.LeftPanel, 'state');
            app.FilterButton.ValueChangedFcn = createCallbackFcn(app, @FilterButtonValueChanged, true);
            app.FilterButton.Tooltip = {'filter out traces that do not have values above threshold'};
            app.FilterButton.Text = 'Filter';
            app.Lamp = uilamp(app.LeftPanel);
            app.Lamp.Tooltip = {'green: data meets criteria'; 'yellow: data does not meet format criteria'; 'red: data was not found'};
            app.HeatmapButton = uibutton(app.LeftPanel, 'state');
            app.HeatmapButton.ValueChangedFcn = createCallbackFcn(app, @HeatmapButtonValueChanged, true);
            app.HeatmapButton.Text = 'Heatmap';
            app.WaterfallButton = uibutton(app.LeftPanel, 'state');
            app.WaterfallButton.ValueChangedFcn = createCallbackFcn(app, @WaterfallButtonValueChanged, true);
            app.WaterfallButton.Tooltip = {'map traces to #, from top to bottom'};
            app.WaterfallButton.Text = 'Waterfall';
            app.dffdataname = uieditfield(app.LeftPanel, 'text');
            app.dffdataname.Value = 'dffmat';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            % Show the figure after all components are created
            app.GeorgesCalciumTraceNavigatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calcium_trace_navigator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GeorgesCalciumTraceNavigatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GeorgesCalciumTraceNavigatorUIFigure)
        end
    end
end